classdef horizontal_App_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SubjectEditFieldLabel  matlab.ui.control.Label
        SubjectEditField       matlab.ui.control.NumericEditField
        ProgressGaugeLabel     matlab.ui.control.Label
        ProgressGauge          matlab.ui.control.LinearGauge
        AnglePickButtonGroup   matlab.ui.container.ButtonGroup
        UIAxes                 matlab.ui.control.UIAxes
        Button1_2              matlab.ui.control.RadioButton
        Button1_3              matlab.ui.control.RadioButton
        Button1_4              matlab.ui.control.RadioButton
        Button1_5              matlab.ui.control.RadioButton
        Button1_6              matlab.ui.control.RadioButton
        Button1_7              matlab.ui.control.RadioButton
        Button1_8              matlab.ui.control.RadioButton
        Button1_9              matlab.ui.control.RadioButton
        FSEditFieldLabel       matlab.ui.control.Label
        FSEditField            matlab.ui.control.NumericEditField
        Panel                  matlab.ui.container.Panel
        PlaySoundButton        matlab.ui.control.Button
        ConfirmChoiceButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        hrtf_struct; % Description
        fs % Description
        R_hrtf_array
        R_azs_array
        sigRep
        sigRepPrep
        makesignal
        durBase % Description
        fullSig
        mono
        azs_ground_truth
        choice_result % Description
        click_num
        test_num
        timer % Description
        HRTF_Pick
        az_Pick
        hrir_r
        hrir_l
        time
    end
    
    methods (Access = private)
        
        %%--- Make Stimuli
        
        function pinkn = mk_pink(app,npts)
            
            Den = poly([0.99572754 0.94790649 0.53567505]);    %set up the denominator and numerator polynomials for a digital IIR pink-noise filter
            Num = poly([0.98443604 0.83392334 0.07568359]);
            
            seg = rand(size(1:npts));
            seg = seg - mean(seg);
            pinkn = filter(Num,Den,seg);      %create the pink noise signal
        end
        % --- make infrapitch
        function y = mk_infrapitch(app)
            app.durBase = 1/app.sigRep; % 0.2 per base
            for i=1:length(app.fs)
                infraBase(i).base = mk_pink(app,ceil(app.fs(i)*app.durBase));
            end
            
            for i=1:length(infraBase)
                app.fullSig(i).fullsig = [];
                for iRep = 1:app.sigRepPrep
                    app.fullSig(i).fullsig = [app.fullSig(i).fullsig, infraBase(i).base];
                end
                app.mono(i).mono = app.fullSig(i).fullsig;
                mono_for_save=app.mono(i).mono;
                filenm=['infrapitch' num2str(i) '.mat'];
                save(filenm, 'mono_for_save')
                
            end


            %app.mono = app.fullSig(1:ceil(min(app.fs*app.sigLength, length(app.fullSig))));
            y = app;
        end
        
        function y = mk_signal(app)
            clear mono
            clear app.mono
            app = mk_infrapitch(app);
            disp('making infrapitch');
            y = app;
        end
        
        % generate a random play list of hrtf, 3 hrtfs randomly but on quantity evenly scatter into 120 repetations
        function R_hrtf_array=make_hrtf_play_list(app)
            hrtf_index=[];
            for i=1:40
                
                for j=1:length(app.hrtf_struct)
                    hrtf_index=[hrtf_index,j];
                end
            end
            R_hrtf_array=hrtf_index(randperm(length(hrtf_index)));
            
        end
        % generate a random play list of azimuth, 0,30,60,90,120,150,180,210,240,270,300,330 evenly scatter in the list.
        function R_azs_array=make_azs_play_list(app)
            azs_index=[];
            for i=1:10
                for j=1:12
                    azs_index=[azs_index,j];
                end
            end
            R_azs_array=azs_index(randperm(length(azs_index)));
            for i=1:length(R_azs_array)
                app.azs_ground_truth(i)=app.hrtf_struct(1).HRTF(R_azs_array(i)).az;
            end
        end
        
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %app.AnglePickButtonGroup.Children = flip(app.AnglePickButtonGroup.Children);
            % Fill figure with axes and remove tick labels
            app.UIAxes.Position = [0 0 app.AnglePickButtonGroup.Position(3:4)];
            % Remove title, axis labels, and tick labels
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imshow('draw1.jpg', 'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            num = 0; % number of hrtfs loaded
            path = uigetdir(pwd,'Please select the folder');
            filelist = dir(sprintf('%s/*.mat',path));
            for i = 1 : length(filelist)
                clear data specs
                num = num + 1;
                fprintf('Now loading: %s\n',filelist(i).name);
                data_struct=load(sprintf('%s/%s',path,filelist(i).name));
                app.hrtf_struct(i).HRTF= data_struct.HRTF;
%                 app.hrtf_struct(i).HRTF.hrir_l= data_struct.HRTF.hrir_l;
%                 app.hrtf_struct(i).HRTF.hrir_r= data_struct.HRTF.hrir_r;
%                 app.hrtf_struct(i).HRTF.srate= data_struct.HRTF.srate;
                %                 app.hrtf_struct(i).name = filelist(i).name;
            end
            %generate a hrtf play list
            app.R_hrtf_array=make_hrtf_play_list(app);
            %generate a azimuth play list
            app.R_azs_array=make_azs_play_list(app);
            
            % to initialize the sampling frequency
            for i=1:length(app.hrtf_struct)
                app.fs(i) = app.hrtf_struct(i).HRTF(1).srate;
            end
            set(app.FSEditField,'Value',app.hrtf_struct(app.R_hrtf_array(1)).HRTF(1).srate)
            
            % to specify parameters for making input
            app.sigRep = 10;
            app.sigRepPrep = 10;
            % to make input signal
            app.makesignal = mk_signal(app); % to be saved in app.mono
            % Set the timer
            app.timer = timer('StartDelay',0.5, ...
                'ExecutionMode', 'fixedSpacing', ...       % Run timer repeatedly
                'Period', 1, ...                        % Initial period is 1 sec.
                'TasksTOExecute', 2, ...
                'TimerFcn', @PlaySoundButtonPushed); % Specify callback function
            %--initiate an array to store test result
            app.choice_result=zeros(1,72);
            % initialize a counter for count click save number
            app.click_num=1;
            % initialize a counter for count test times
            app.test_num=1;
        end

        % Button pushed function: PlaySoundButton
        function PlaySoundButtonPushed(app, event)
            set(app.SubjectEditField,'Editable','off')
            %select one htrf from playlist
            app.HRTF_Pick=app.R_hrtf_array(app.click_num);
            %select one azimuth from playlist
            app.az_Pick=app.R_azs_array(app.click_num);
            
            app.hrir_r=app.hrtf_struct(app.HRTF_Pick).HRTF(app.az_Pick).hrir_r;
            app.hrir_l=app.hrtf_struct(app.HRTF_Pick).HRTF(app.az_Pick).hrir_l;
            IR_r=app.hrir_r;
            IR_l=app.hrir_l;
            left = conv(IR_l,app.mono(app.HRTF_Pick).mono);
            right = conv(IR_r,app.mono(app.HRTF_Pick).mono);
            if isrow(left)
                left = left';
                right = right';
                v = [left,right];
            end
            set(app.FSEditField,'Value',app.fs(app.HRTF_Pick))
            sound(v,app.fs(app.HRTF_Pick));
            start(timer);
            %set(app.PlaySoundButton,'Enable','off')
            set(app.ConfirmChoiceButton,'Enable','on')
            %start a timer
            time=cputime;
            app.time=time;
            
            azs=(app.azs_ground_truth(app.click_num))
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SubjectEditFieldLabel = uilabel(app.UIFigure);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProgressGaugeLabel = uilabel(app.UIFigure);
            app.ProgressGaugeLabel.Text = 'Progress';
            app.ProgressGauge = uigauge(app.UIFigure, 'linear');
            app.ProgressGauge.Limits = [1 120];
            app.ProgressGauge.MajorTicks = [1 11 21 31 41 51 61 71 81 91 101 111 120];
            app.ProgressGauge.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', '', '', ''};
            app.ProgressGauge.MinorTicks = [];
            app.ProgressGauge.Value = 1;
            app.AnglePickButtonGroup = uibuttongroup(app.UIFigure);
            app.AnglePickButtonGroup.Title = 'Angle Pick';
            app.UIAxes = uiaxes(app.AnglePickButtonGroup);
            app.Button1_2 = uiradiobutton(app.AnglePickButtonGroup);
            app.Button1_2.Text = '5';
            app.Button1_2.Value = true;
            app.Button1_3 = uiradiobutton(app.AnglePickButtonGroup);
            app.Button1_3.Text = '5';
            app.Button1_4 = uiradiobutton(app.AnglePickButtonGroup);
            app.Button1_4.Text = '5';
            app.Button1_5 = uiradiobutton(app.AnglePickButtonGroup);
            app.Button1_5.Text = '5';
            app.Button1_6 = uiradiobutton(app.AnglePickButtonGroup);
            app.Button1_6.Text = '5';
            app.Button1_7 = uiradiobutton(app.AnglePickButtonGroup);
            app.Button1_7.Text = '5';
            app.Button1_8 = uiradiobutton(app.AnglePickButtonGroup);
            app.Button1_8.Text = '5';
            app.Button1_9 = uiradiobutton(app.AnglePickButtonGroup);
            app.Button1_9.Text = '5';
            app.FSEditFieldLabel = uilabel(app.UIFigure);
            app.FSEditFieldLabel.Text = 'FS';
            app.FSEditField = uieditfield(app.UIFigure, 'numeric');
            app.Panel = uipanel(app.UIFigure);
            app.PlaySoundButton = uibutton(app.Panel, 'push');
            app.PlaySoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySoundButtonPushed, true);
            app.PlaySoundButton.Text = 'Play Sound';
            app.ConfirmChoiceButton = uibutton(app.Panel, 'push');
            app.ConfirmChoiceButton.Text = 'Confirm Choice';
        end
    end

    methods (Access = public)

        % Construct app
        function app = horizontal_App_v1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end